classdef trial_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartPosEditField       matlab.ui.control.EditField
        StartPosEditFieldLabel  matlab.ui.control.Label
        FilePathEditField       matlab.ui.control.EditField
        FilePathLabel           matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        fileNames           % all of the files in the specified file path
        data                % 3D matrix containing all the data
        croppedData         % 3D matrix containing all the cropped data
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)
            %% Load names of files from specified file path
            value = app.FilePathEditField.Value;
            file = dir(value);

            for i = 1:length(file)
                fileName = convertCharsToStrings(file(i).name);
                
                if contains(fileName, ".txt")
                    app.fileNames = [app.fileNames, fileName];
                end
            end

            %% Load data from files
            %  Find longest file
            lengths = zeros(1,length(app.fileNames));
            for i = 1:length(app.fileNames) 
                    lengths(i) = length(table2array(readtable(app.fileNames(i))));
            end
            %  Get all the data
            app.data = zeros(max(lengths), 4, length(app.fileNames)); % makes an array of zeros that is the longest data set long, with 4 columns, and the number of files amount of pages
            for i = 1:length(app.fileNames) 
                    app.data(1:lengths(i),:,i) = table2array(readtable(app.fileNames(i)));
            end
        end

        % Value changed function: StartPosEditField
        function StartPosEditFieldValueChanged(app, event)
            value = app.StartPosEditField.Value;
            
            %% Extract desired info from loaded data set
            start = value;                                      
            
            time_step = round(mean(app.data(:,2,:), [1, 2, 3]));    % average time beween each reading
            time_end = time_step*(length(app.data)-1);              % final value of time vector
            time = 0:time_step:time_end;
            time = time';                                       % transposing to same dimensions as the other data
            time = time/1000/1000;                              % converting to seconds
            
            
            %% Time plots of signals
            %  Subplots of all individual data sets
            figure
            cropLength = 1800;
            app.croppedData = zeros(cropLength+1,4,length(app.fileNames));
            for i = 1:length(app.fileNames)
                    subplot(2,3,i)
                    %[maxVal,maxLoc] = max(data(:,3,i));
                    maxLoc = 1;
                    app.croppedData(:,:,i) = app.data(maxLoc:maxLoc+cropLength,:,i);
                    app.croppedData(:,3,i) = app.croppedData(:,3,i) - mean(app.croppedData(:,3,i));             % centering around 0
                    app.croppedData(:,4,i) = (app.croppedData(:,4,i) - mean(app.croppedData(:,4,i)))*-1;        % centering around 0 and flipping
                    xlabel("time (s)")
                    ylabel("raw ADC reading (0-32,768)")
                    title("ADC value vs time")
                    plot(time(1:cropLength+1), croppedData(:,3,i), time(1:cropLength+1), croppedData(:,4,i))
                    legend("hall 1 - top", "hall 2 - bottom")
            end
            
            % Average of all signals
            hall_1 = mean(croppedData(:,3,:), [2,3]);
            hall_2 = mean(croppedData(:,4,:), [2,3]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preview of Data')
            xlabel(app.UIAxes, 'data point')
            ylabel(app.UIAxes, 'raw ADC')
            zlabel(app.UIAxes, 'Z')
            app.FilePathLabel = uilabel(app.UIFigure);
            app.FilePathLabel.Text = 'File Path: ';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.StartPosEditFieldLabel = uilabel(app.UIFigure);
            app.StartPosEditFieldLabel.Text = 'Start Pos:';
            app.StartPosEditField = uieditfield(app.UIFigure, 'text');
            app.StartPosEditField.ValueChangedFcn = createCallbackFcn(app, @StartPosEditFieldValueChanged, true);
            app.StartPosEditField.Placeholder = '1';
            app.StartPosEditField.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trial_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end